classdef RP_All_Site < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoseGeneratorUIFigure       matlab.ui.Figure
        HelpMenu                    matlab.ui.container.Menu
        HelpcontentsMenu            matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        Label                       matlab.ui.control.Label
        OARsListBox                 matlab.ui.control.ListBox
        OARsListBoxLabel            matlab.ui.control.Label
        CTVsListBox                 matlab.ui.control.ListBox
        CTVsListBoxLabel            matlab.ui.control.Label
        PTVsListBox                 matlab.ui.control.ListBox
        PTVsListBoxLabel            matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        Image3                      matlab.ui.control.Image
        WriteRTplanButton           matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        StatusLabel                 matlab.ui.control.Label
        ViewDoseDistributionButton  matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        ReadContoursButton          matlab.ui.control.Button
        WriteRTdoseButton           matlab.ui.control.Button
        GenerateDoseButton          matlab.ui.control.Button
        LoadDicomButton             matlab.ui.control.Button
    end

    properties (Access = public)
        StructureNames       cell % Description
        ctData               double
        ctFiles              cell
        DoseInfo             struct
        PTV                  struct
        CTV                  struct
        OAR                  struct
        ctInfo               struct
        ContourSeq           struct
        Body                 struct
        RSInfo               struct
        Dose                 double
        GenDose              struct
        WorkFolder           cell  
        PlanInfo             struct
    end

    methods (Access = private)
        % Method to read selected contours
        function ReadSelectedContours(app)
            try
                % Get selected items
                list_contours{1,1} = app.PTVsListBox.Value;
                list_contours{2,1} =app.CTVsListBox.Value;
                list_contours{3,1} =app.OARsListBox.Value;
                list_type = {'PTV'; 'CTV'; 'OAR'};
                for t=1:3
                    % Combine all selected structures
                    selectedStructures = list_contours{t,1};
                    for i = 1:length(selectedStructures)
                        structureName = selectedStructures{i};
                        % Find ROI index for the structure
                        roiIndex = find(strcmp(app.StructureNames, structureName), 1);
                        if isempty(roiIndex)
                            % If structure not found in the ROI sequence, skip
                            continue;
                        end
                        % Extract ROI contour sequence
                        roiContourSeq = app.ContourSeq.(sprintf('Item_%d', roiIndex)); %%%%%%
                        contourdata = roiContourSeq.ContourSequence;

                        fn = fieldnames(contourdata);
                        seg = zeros(size(app.ctData));
                        for j = 1:numel(fn)
                            item = contourdata.(fn{j});
                            if strcmpi(item.ContourGeometricType, 'CLOSED_PLANAR')
                                refuid = item.ContourImageSequence.Item_1.ReferencedSOPInstanceUID;
                                sliceind = find(strcmp(refuid,app.ctInfo.SOPInstanceUID),1,'first');
                                if isempty(sliceind)
                                    warning(['Missing image with UID reference in RTSTRUCT: ' refuid]);
                                end

                                contourpts = item.ContourData;                              

                                IOP = app.ctInfo.ImageOrientationPatient{sliceind};
                                IPP = app.ctInfo.ImagePositionPatient{sliceind};
                                PS = app.ctInfo.PixelSpacing{sliceind};

                                M = [IOP(1)*PS(1) IOP(4)*PS(2) 0 IPP(1);
                                    IOP(2)*PS(1) IOP(5)*PS(2) 0 IPP(2);
                                    IOP(3)*PS(1) IOP(6)*PS(2) 1 IPP(3);
                                    0            0            0 1      ];
                                contourpts = reshape(contourpts,[3 size(contourpts,1)/3]);
                                contourpts(4,:) = 1;

                                contourpts_rcs = zeros(size(contourpts));
                                for k = 1:size(contourpts,2)
                                    contourpts_rcs(:,k) = M\contourpts(:,k);
                                end

                                xpoly = 1+contourpts_rcs(1,:); % since DICOM index is from 0, but MATLAB
                                ypoly = 1+contourpts_rcs(2,:); % works from 1

                                contourmask = poly2mask(xpoly,ypoly,size(app.ctData,1),size(app.ctData,2));
                                seg(:,:,sliceind) = contourmask | seg(:,:,sliceind);
                            end
                        end
                        % Store the rasterized contours in the app for further processing
                        app.(list_type{t,1})(i).mask = seg;
                        app.(list_type{t,1})(i).name = structureName;

                    end
                end
                app.Label.Text = "Selected contours successfully rasterized and aligned with CT data.";
            catch ME
                app.Label.Text = "Error processing selected contours!";
                disp(ME.message);
            end
        end

        % Function to read CT images and store in 3D array
        function [ctData, ctInfo] = readCTImages(app)
            f = 1;
            for j = 1:numel(app.ctFiles)
                fn = app.ctFiles{1,j};
                dinfo{f} = dicominfo(fn); % metadata (cell since struct fields can vary)
                ctData(:,:,f) = dicomread(dinfo{f}); % pixeldata
                f = f+1;
            end
            IOP_xyz = dinfo{1}.ImageOrientationPatient; % direction cosines relating i vector to xyz and j vector to xyz
            idir_xyz = IOP_xyz(1:3); % direction vector for rows in xyz
            jdir_xyz = IOP_xyz(4:6); % direction vector for columns in xyz
            kdir_xyz = cross(idir_xyz,jdir_xyz); % previously compared IPP(1) and IPP(end) but sign problems in sagittal images

           kdir_xyz = kdir_xyz/norm(kdir_xyz);
            img_sl = cellfun(@(x)dot(x.ImagePositionPatient,kdir_xyz),dinfo); % Slice order metric (more reliable than SliceLocation)
            img_sl = img_sl-min(img_sl(:));
            [~,ord] = sort(img_sl,'ascend');

            ctData = ctData(:,:,ord);
            dinfo = dinfo(ord);
            % Apply Rescale Slope and Intercept to convert to Hounsfield Units
            for ii = 1:size(ctData, 3)
                slope = dinfo{ii}.RescaleSlope; % Get rescale slope
                intercept = dinfo{ii}.RescaleIntercept; % Get rescale intercept
                ctData(:,:,ii) = ctData(:,:,ii) * slope + intercept; % Convert to HU
            end

            ctInfo = struct();
            ctInfo.sort = ord;
            % Keep a subset of DICOM metadata - only first-level fields with numeric or
            % char datatypes, whose value is the same for every instance
            fn = fieldnames(dinfo{1});
            for ik = 1:numel(fn)
                keep = false;
                refinfo = dinfo{1}.(fn{ik});
                for j = 2:numel(dinfo)
                    if isfield(dinfo{j},fn{ik})
                        testinfo = dinfo{j}.(fn{ik});
                        if strcmp(class(refinfo),class(testinfo)) && ...
                                ( ...
                                ( isnumeric(refinfo) && (numel(refinfo) == numel(testinfo)) && all(refinfo == testinfo) ) ...
                                || ...
                                ( ischar(refinfo) && strcmp(refinfo,testinfo) ) ...
                                )
                            keep = true;
                        end
                    else
                        keep = false;
                    end
                    if ~keep; break; end;
                end
                if keep
                    ctInfo.(fn{ik}) = dinfo{1}.(fn{ik});
                end
            end

            % Keep some important DICOM metadata for every slice
            fieldcontents = @(f)cellfun(@(x){x.(f)},dinfo);
            ctInfo.SOPInstanceUID = fieldcontents('SOPInstanceUID');
            ctInfo.ImageOrientationPatient =  fieldcontents('ImageOrientationPatient');
            ctInfo.ImagePositionPatient = fieldcontents('ImagePositionPatient');
            ctInfo.PixelSpacing = fieldcontents('PixelSpacing');
        end

        % Callback to handle dose submission
        function submitDoses(app, selectedTargets, doseInputs, fig)
            try
                % Iterate through selected targets (both PTVs and CTVs)
                for row = 1:size(selectedTargets, 1)  % Loop through rows (PTVs and CTVs)
                    for col = 1:size(selectedTargets, 2)  % Loop through columns (individual targets)
                        targetName = selectedTargets{row, col};  % Get the name of the target
                        doseValue = doseInputs{row, col}.Value;  % Get the dose from the input field

                        % Check for valid dose value
                        if isempty(doseValue) || isnan(doseValue) || doseValue <= 0
                            uialert(fig, sprintf('Invalid dose for %s. Please enter a positive value.', targetName), 'Input Error');
                            return;
                        end

                        % Assign dose value to the corresponding PTV or CTV in app.PTV and app.CTV
                        % First, check for PTVs
                        for p = 1:length(app.PTV)
                            if strcmp(app.PTV(p).name, targetName)
                                app.PTV(p).dose = doseValue;
                            end
                        end

                        % Then, check for CTVs
                        for c = 1:length(app.CTV)
                            if strcmp(app.CTV(c).name, targetName)
                                app.CTV(c).dose = doseValue;
                            end
                        end
                    end
                end

                % Close the figure and resume execution
                uiresume(fig);
                delete(fig);
            catch ME
                disp("Error saving prescribed doses:");
                disp(ME.message);
            end
        end


        % Read BODY contour
        function [structureName, seg] = BodyContourRead(app)
            fn = fieldnames(app.RSInfo.StructureSetROISequence);
            for f = 1:numel(fn)
                if strcmpi(app.RSInfo.StructureSetROISequence.(fn{f}).ROIName,'BODY')
                    structureName = app.RSInfo.StructureSetROISequence.(fn{f}).ROIName;
                    % Extract ROI contour sequence
                    contourdata = app.RSInfo.ROIContourSequence.(fn{f}).ContourSequence;
                    fn = fieldnames(contourdata);
                    seg = zeros(size(app.ctData));
                    for j = 1:numel(fn)
                        item = contourdata.(fn{j});
                        if strcmpi(item.ContourGeometricType, 'CLOSED_PLANAR')
                            refuid = item.ContourImageSequence.Item_1.ReferencedSOPInstanceUID;
                            sliceind = find(strcmp(refuid,app.ctInfo.SOPInstanceUID),1,'first');
                            if isempty(sliceind)
                                warning(['Missing image with UID reference in RTSTRUCT: ' refuid]);
                            end
                            contourpts = item.ContourData;
                            % Find conversion from RCS (used in RTSTRUCT) to DICOM pixel indices by
                            % inverting the transform given in Equation C.7.6.2.1-1 of the DICOM
                            % specification (including an extra 1 because otherwise the matrix is
                            % singular - this is OK because 3rd component of input\output vector is zero)
                            IOP = app.ctInfo.ImageOrientationPatient{sliceind};
                            IPP = app.ctInfo.ImagePositionPatient{sliceind};
                            PS = app.ctInfo.PixelSpacing{sliceind};
                            M = [IOP(1)*PS(1) IOP(4)*PS(2) 0 IPP(1);
                                IOP(2)*PS(1) IOP(5)*PS(2) 0 IPP(2);
                                IOP(3)*PS(1) IOP(6)*PS(2) 1 IPP(3);
                                0            0            0 1      ];
                            contourpts = reshape(contourpts,[3 size(contourpts,1)/3]);
                            contourpts(4,:) = 1;
                            contourpts_rcs = zeros(size(contourpts));
                            for k = 1:size(contourpts,2)
                                contourpts_rcs(:,k) = M\contourpts(:,k);
                            end
                            xpoly = 1+contourpts_rcs(1,:); % since DICOM index is from 0, but MATLAB
                            ypoly = 1+contourpts_rcs(2,:); % works from 1
                            contourmask = poly2mask(xpoly,ypoly,size(app.ctData,1),size(app.ctData,2));
                            seg(:,:,sliceind) = contourmask | seg(:,:,sliceind);
                        end
                    end
                end
            end
        end
        % Expand contours
        function expanded_volume = GrowMargin(app, contour_name)
            volume_3d = app.GenDose.(contour_name);
            margin_mm = app.GenDose.Margin;
            res = app.GenDose.Res;
            % Convert margin in mm to number of voxels (rounded)
            margin_voxels = round(margin_mm ./ res);
            % Create a 3D structuring element (cube) with appropriate margins
            se = strel('arbitrary', ones( ...
                2 * margin_voxels(1) + 1, ...
                2 * margin_voxels(3) + 1, ...
                2 * margin_voxels(5) + 1));
            % Apply 3D dilation
            expanded_volume = imdilate(volume_3d, se);
        end


        % Resample to 1x1x1mm
        function new_mask = resample_data(app,old_pixel,new_pixel)
            %Resample the grid to new resolution
            mask = app.Dose;
            %Define original matrix grid
            [x, y, z] = meshgrid(old_pixel(2)*[0:1:size(mask,2)-1],old_pixel(1)*[0:1:size(mask,1)-1],...
                old_pixel(3)*[0:1:size(mask,3)-1]);
            %Define new interpolated  grid
            [xi, yi, zi] = meshgrid(old_pixel(2)*[0:new_pixel(2)/old_pixel(2):size(mask,2)-1],...
                old_pixel(1)*[0:new_pixel(1)/old_pixel(1):size(mask,1)-1],...
                old_pixel(3)*[0:new_pixel(3)/old_pixel(3):size(mask,3)-1]);
            %Interpolate to matrix
            new_mask = interp3(x,y,z,mask,xi,yi,zi);
        end

        % Function to reset the app to its initial state
        function resetApp(app)
            % Clear app properties
            app.StructureNames      ={};
            app.ctData              =[];
            app.DoseInfo            =[];
            app.PTV                 =[];
            app.CTV                 =[];
            app.OAR                 =[];
            app.ctInfo              =[];
            app.ContourSeq          =[];
            app.Body                =[];
            app.RSInfo              =[];
            app.Dose                =[];
            app.GenDose             =[];
            app.WorkFolder          ={};    
           

            % Enable and highlight the first button           
            app.LoadDicomButton.BackgroundColor = [0.47, 0.67, 0.19]; % Green
            app.LoadDicomButton.FontWeight = 'bold';
            % Reset all buttons
            app.ReadContoursButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default
            app.ReadContoursButton.FontWeight = 'normal';
            app.ReadContoursButton.Enable = 'off';

            app.GenerateDoseButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default
            app.GenerateDoseButton.FontWeight = 'normal';
            app.GenerateDoseButton.Enable = 'off';

            app.WriteRTdoseButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default
            app.WriteRTdoseButton.FontWeight = 'normal';
            app.WriteRTdoseButton.Enable = 'off';

            app.ViewDoseDistributionButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default
            app.ViewDoseDistributionButton.FontWeight = 'normal';
            app.ViewDoseDistributionButton.Enable = 'off';            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Enable and highlight the next button           
            app.LoadDicomButton.BackgroundColor = [0.47, 0.67, 0.19]; % Green
            app.LoadDicomButton.FontWeight = 'bold';            
        end

        % Button pushed function: LoadDicomButton
        function LoadDicomButtonPushed(app, event)
            % Reset app properties and UI components
            resetApp(app);
            % Prompt user to select a folder
            folder = uigetdir();
            if folder == 0
                app.Label.Text = "No folder selected!";
                return;
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d = uiprogressdlg(app.DoseGeneratorUIFigure,'Title','Please Wait',...
                'Message','Reading Dicom files...','Cancelable', 'on');
            d.Value = 0.1;
            pause(.5)
            % Check if the user canceled
            if d.CancelRequested
                app.Label.Text = 'Operation cancelled by user.';               
                return;
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

            % Load all DICOM files in the folder
            dicomFiles = dir(fullfile(folder, '**', '*.dcm'));
            if isempty(dicomFiles)
                app.Label.Text = "No DICOM files found!";
                return;
            end

            % Separate CT, RTSTRUCT, RT plan and RTDOSE files
            app.ctFiles = {};
            rtstructFile = '';
            for i = 1:length(dicomFiles)
                filePath = fullfile(dicomFiles(i).folder, dicomFiles(i).name);
                info = dicominfo(filePath);
                switch info.Modality
                    case 'CT'
                        app.ctFiles{end+1} = filePath; 
                    case 'RTSTRUCT'
                        rtstructFile = filePath;
                    case 'RTDOSE'
                        app.DoseInfo = dicominfo(filePath);
                    case 'RTPLAN'
                        app.PlanInfo = dicominfo(filePath);
                end
            end
            % store the DCM file folder
            app.WorkFolder = {folder};

            % check all DICOM files are present
            if isempty(app.ctFiles)
                app.Label.Text = "No CT data found!";
                return;
            elseif isempty(rtstructFile)
                app.Label.Text = "No RTSTRUCT file found!";
                return;
            elseif isempty(app.DoseInfo)
                app.Label.Text = "No RTDOSE file found!";
                return;
            end
            app.ctFiles = app.ctFiles;

            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d.Value = .33; 
            d.Message = 'Reading CT image...';
            pause(0.5)
            % Check if the user canceled
            if d.CancelRequested
                app.Label.Text = 'Operation cancelled by user.';
                return;
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

            % Read CT images and store in a 3D array
            try
                [app.ctData, app.ctInfo] = readCTImages(app);
            catch ME
                app.Label.Text = "Error reading CT images!";
                disp(ME.message);
                return;
            end

            % Read RTSTRUCT file and list all the contours
            try
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = .63;
                d.Message = 'Reading Contours list...';
                pause(0.5)
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

                info = dicominfo(rtstructFile);
                roiSeq = info.StructureSetROISequence;
                contourSeq = info.ROIContourSequence;
                % Extract contour names
                names = fieldnames(roiSeq);
                app.StructureNames = cellfun(@(name) roiSeq.(name).ROIName, names, 'UniformOutput', false);
                app.ContourSeq = contourSeq;
                app.RSInfo = info;

                % Initialize lists for PTVs, CTVs, and OARs
                PTVList = {};
                CTVList = {};
                OARList = {};

                for i = 1:length(app.StructureNames)
                    if contains(app.StructureNames{i}, 'PTV', 'IgnoreCase', true)
                        PTVList{end+1} = app.StructureNames{i};
                    elseif contains(app.StructureNames{i}, 'CTV', 'IgnoreCase', true)
                        CTVList{end+1} = app.StructureNames{i};
                    else
                        OARList{end+1} = app.StructureNames{i};
                    end
                end

                % Update ListBox items
                app.PTVsListBox.Items = PTVList;
                app.CTVsListBox.Items = CTVList;
                app.OARsListBox.Items = OARList;

                app.PTVsListBox.Multiselect = 'on';
                app.CTVsListBox.Multiselect = 'on';
                app.OARsListBox.Multiselect = 'on';

                % wait for the user to Select PTVs/CTVs and OARs
                uialert(app.DoseGeneratorUIFigure, 'Please select at least one structure (PTV, CTV, OAR) to continue.', 'Selection Required');
                app.ReadContoursButton.Enable = 'on';

                % Reset current button
                app.LoadDicomButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default
                app.LoadDicomButton.FontWeight = 'normal';

                % Enable and highlight the next button
                app.ReadContoursButton.Enable = 'on';
                app.ReadContoursButton.BackgroundColor = [0.47, 0.67, 0.19]; % Green
                app.ReadContoursButton.FontWeight = 'bold';

                app.Label.Text = sprintf("Loaded: %s", rtstructFile);
            catch ME
                app.Label.Text = "Error reading RTSTRUCT file!";
                disp(ME.message);
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d.Value = 1;
            d.Message = 'Finished - Dicom files processing';
            pause(0.5)
            % Close dialog box
            if isvalid(d)
                close(d)
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

        end

        % Button pushed function: ReadContoursButton
        function ReadContoursButtonPushed2(app, event)
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d = uiprogressdlg(app.DoseGeneratorUIFigure,'Title','Please Wait',...
                'Message','Reading Selected Contours...','Cancelable', 'on');
            % Check if the user canceled
            if d.CancelRequested
                app.Label.Text = 'Operation cancelled by user.';
                return;
            end
            pause(.5)
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            % Get selected structures from each list
            selectedPTVs = app.PTVsListBox.Value;
            selectedCTVs = app.CTVsListBox.Value;
            selectedOARs = app.OARsListBox.Value;

            % Combine all selected structures into one cell array
            selectedStructures = [selectedPTVs, selectedCTVs, selectedOARs];

            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d.Value = .3;
            d.Message = 'Reading Selected Contours...';
            % Check if the user canceled
            if d.CancelRequested
                app.Label.Text = 'Operation cancelled by user.';
                return;
            end
            pause(0.5)            
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            % Process the selected contours
            ReadSelectedContours(app);
            app.Label.Text = sprintf("Selected structures: %s", strjoin(selectedStructures, ', '));

            % Read 'External-Body' Contour
            [structureName, seg] = BodyContourRead(app);            
            % Store the rasterized contours in the app for further processing
            app.Body(1).mask = seg;
            app.Body(1).name = structureName;

            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d.Value = 1;
            d.Message = 'Finished - Reading Selected Contours...';
            pause(0.5)                     
            % Close dialog box
            if isvalid(d)
                close(d)
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

           %Check user has selected PTV/CTV/OAR
            if isempty(selectedPTVs)
                uialert(app.DoseGeneratorUIFigure, 'Please select at least one PTV', 'Selection Required');
                return;
            elseif isempty(selectedCTVs)
                uialert(app.DoseGeneratorUIFigure, 'Please select at least one CTV', 'Selection Required');
                return;
            elseif isempty(selectedOARs)
                uialert(app.DoseGeneratorUIFigure, 'Please select at least one OAR', 'Selection Required');
                return;
            end
            
            % Prompt user to enter prescribed dose for selected PTVs
            try
                % Get user-selected PTVs
                selectedPTVs = app.PTVsListBox.Value;
                selectedCTVs = app.CTVsListBox.Value;
                selectedTargets = [selectedPTVs; selectedCTVs];                

                % Display editable text boxes for prescribed doses
                [numRows, numCols] = size(selectedTargets);
                doseInputs = cell(numRows, numCols);  % Store text input handles for doses
                promptText = 'Enter the prescribed dose (Gy) for the selected Targets:';

                % Calculate figure dimensions dynamically
                screenSize = get(0, 'ScreenSize'); % [left, bottom, width, height]
                figWidth = 400; % Fixed minimum width for better layout
                lineHeight = 30; % Height of each text box and label
                verticalPadding = 20; % Padding above and below elements
                promptHeight = 40; % Space for prompt text
                figHeight = promptHeight + verticalPadding * 2 + lineHeight * (numRows*numCols + 2);

                % Center the figure on the screen
                figLeft = (screenSize(3) - figWidth) / 1; % Center horizontally
                figBottom = (screenSize(4) - figHeight) / 2; % Center vertically

                % Create the figure and ensure it comes to the front
                fig = uifigure('Name', 'Prescribed Dose Input', ...
                    'Position', [figLeft, figBottom, figWidth, figHeight], ...
                    'WindowStyle', 'normal'); % Ensure normal window style

                % Bring the figure to the front
                figure(fig);

                % Add a label for instructions
                uilabel(fig, ...
                    'Text', promptText, ...
                    'Position', [20, figHeight - promptHeight - 10, figWidth - 40, promptHeight], ...
                    'HorizontalAlignment', 'left', ...
                    'FontSize', 12);

                % Create text boxes for each selected PTV
                index = 0; % To place UI elements vertically
                for row = 1:numRows
                    for col = 1:numCols
                        index = index + 1;
                        labelY = figHeight - promptHeight - verticalPadding - index * lineHeight;
                        fieldY = labelY;

                        uilabel(fig, ...
                            'Text', selectedTargets{row, col}, ...
                            'Position', [20, labelY, figWidth / 2 - 30, lineHeight], ...
                            'HorizontalAlignment', 'left', ...
                            'FontSize', 12);

                        doseInputs{row, col} = uieditfield(fig, 'numeric', ...
                            'Position', [figWidth / 2 + 10, fieldY, figWidth / 2 - 40, lineHeight]);
                    end
                end

                % Compute Y-position for the Submit button after the last input
                extraSpacing = 5;  % Add some space after last input
                submitY = figHeight - promptHeight - verticalPadding - (index + 1) * lineHeight - extraSpacing;

                submitButton = uibutton(fig, ...
                    'Text', 'Submit', ...
                    'Position', [figWidth / 2 - 50, submitY, 100, 30], ...
                    'ButtonPushedFcn', @(~, ~) submitDoses(app, selectedTargets, doseInputs, fig));

                % Wait for the user to press submit and close the dose input window
                uiwait(fig);

                % Execution resumes here after uiresume() is called in submitDoses
                app.Label.Text = 'Doses entered successfully!!!';

                % Reset current button
                app.ReadContoursButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default
                app.ReadContoursButton.FontWeight = 'normal';

                % Enable and highlight the next button
                app.GenerateDoseButton.Enable = 'on';
                app.GenerateDoseButton.BackgroundColor = [0.47, 0.67, 0.19]; % Green
                app.GenerateDoseButton.FontWeight = 'bold';
            catch ME
                app.Label.Text = "Error handling PTV dose input!";
                disp(ME.message);
            end 

        end

        % Button pushed function: GenerateDoseButton
        function GenerateDoseButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d = uiprogressdlg(app.DoseGeneratorUIFigure,'Title','Please Wait',...
                'Message','Generating dose distribution...','Cancelable', 'on');
            % Check if the user canceled
            if d.CancelRequested
                app.Label.Text = 'Operation cancelled by user.';
                return;
            end
            pause(.5)
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

            try
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = .1;
                d.Message = 'Modeling PTV/CTV dose distribution...';
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                pause(0.5)
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                % extract CTVs
                ctvs = zeros(size(app.ctData));
                for c=1:length(app.CTV)
                    ctvs = ctvs+app.CTV(c).mask;
                end

                %% === Extract Masks for CTVs and OARs ===               
                ctvs = sum(cat(4, app.CTV.mask), 4);
                OARs = sum(cat(4, app.OAR.mask), 4);
                OARs = OARs .* ~ctvs;  % Exclude overlap with CTVs
                body = app.Body.mask;

                %% Model PTV doses
                x_res = app.ctInfo.PixelSpacing{1, 1}(1);
                y_res = app.ctInfo.PixelSpacing{1, 1}(2);
                z_res = app.ctInfo.SliceThickness;

                %% === Classify PTVs and CTVs by Dose Levels ===
                % Initialize variables
                ptv_high = zeros(size(app.ctData)); high_dose_ptv = 0;% Zero matrix for missing PTVs
                ptv_low = zeros(size(app.ctData)); low_dose_ptv = 0;
                ptv_mid = zeros(size(app.ctData)); mid_dose_ptv = 0;
                
                dose_ptv = [app.PTV.dose]; dose_ctv = [app.CTV.dose];
                max_pres_dose = max([dose_ptv dose_ctv]);
                [high_dose_ptv, idx_high_ptv] = max(dose_ptv);
                [low_dose_ptv, idx_low_ptv] = min(dose_ptv);

                ptv_high = app.PTV(idx_high_ptv).mask;
                if numel(app.PTV) > 1
                    ptv_low = app.PTV(idx_low_ptv).mask;
                end
                if numel(app.PTV) == 3
                    idx_mid = setdiff(1:3, [idx_high_ptv, idx_low_ptv]);
                    ptv_mid = app.PTV(idx_mid).mask;
                    mid_dose_ptv = dose_ptv(idx_mid);
                end
                % Initialize variables
                ctv_high = zeros(size(app.ctData)); high_dose_ctv = 0;% Zero matrix for missing CTVs
                ctv_low = zeros(size(app.ctData)); low_dose_ctv = 0;
                ctv_mid = zeros(size(app.ctData)); mid_dose_ctv = 0;

                [high_dose_ctv, idx_high_ctv] = max(dose_ctv);
                [low_dose_ctv, idx_low_ctv] = min(dose_ctv);
                ctv_high = app.CTV(idx_high_ctv).mask;
                if numel(app.CTV) > 1
                    ctv_low = app.CTV(idx_low_ctv).mask;
                end
                if numel(app.CTV) == 3
                    idx_mid = setdiff(1:3, [idx_high_ctv, idx_low_ctv]);
                    ctv_mid = app.CTV(idx_mid).mask;
                    mid_dose_ctv = dose_ctv(idx_mid);
                end

                %% === Store Masks in app.GenDose ===
                app.GenDose = struct( ...
                    'ptv_high', ptv_high, 'ptv_mid', ptv_mid, 'ptv_low', ptv_low, ...
                    'ctv_high', ctv_high, 'ctv_mid', ctv_mid, 'ctv_low', ctv_low, ...
                    'OARs', OARs, ...
                    'Res', [x_res, y_res, z_res, x_res, y_res, z_res]);

                %% === Expand Contours Using Margins ===
                app.GenDose.Margin = [x_res, x_res, x_res, x_res, z_res, z_res];
                enptv_high = GrowMargin(app, 'ptv_high');
                enptv_mid  = GrowMargin(app, 'ptv_mid');
                enptv_low  = GrowMargin(app, 'ptv_low');
                enctv_high = GrowMargin(app, 'ctv_high');
                enctv_mid  = GrowMargin(app, 'ctv_mid');
                enctv_low  = GrowMargin(app, 'ctv_low');
             
                % High CTV/PTV coverage
                pres_dose = (high_dose_ctv/max_pres_dose)*100;                
                ctv_high = (pres_dose + (pres_dose*1.05 - pres_dose) * rand(size(enctv_high))).*enctv_high;
                pres_dose = (high_dose_ptv/max_pres_dose)*100;
                ptv_high = (pres_dose + (pres_dose*1.05 - pres_dose) * rand(size(enptv_high))).*enptv_high;
                % crop from CTV_High               
                ptv_high = ptv_high.*~double(enctv_high);
                % Crop from OARs and reduce the dose
                ptv_high(OARs & enptv_high) = min(ptv_high(OARs & enptv_high), 0.96*pres_dose);
                
                % Mid CTV/PTV coverage
                pres_dose = (mid_dose_ctv/max_pres_dose)*100;
                ctv_mid = (pres_dose + (pres_dose*1.05 - pres_dose) * rand(size(enctv_mid))).*enctv_mid;                
                pres_dose = (mid_dose_ptv/max_pres_dose)*100;
                ptv_mid = (pres_dose + (pres_dose*1.05 - pres_dose) * rand(size(enptv_mid))).*enptv_mid;
                ptv_mid(OARs & enptv_mid) = min(ptv_mid(OARs & enptv_mid), 0.96*pres_dose);%OARs crop
                % crop from CTV/PTV High
                ctv_mid = ctv_mid.*~enctv_high.*~enptv_high;                
                ptv_mid = ptv_mid.*~enctv_high.*~enptv_high.*~enctv_mid;

                % Low CTV/PTV coverage                
                pres_dose = (low_dose_ctv/max_pres_dose)*100;
                ctv_low = (pres_dose + (pres_dose*1.05 - pres_dose) * rand(size(enctv_low))).*enctv_low;
                pres_dose = (low_dose_ptv/max_pres_dose)*100;
                ptv_low = (pres_dose + (pres_dose*1.05 - pres_dose) * rand(size(enptv_low))).*enptv_low;
                ptv_low(OARs & enptv_low) = min(ptv_low(OARs & enptv_low), 0.96*pres_dose);%OARs crop

                % crop from CTV/PTV High, MID                
                ctv_low = ctv_low.*~enctv_high.*~enptv_high.*~enctv_mid.*~enptv_mid;                
                ptv_low = ptv_low.*~enctv_high.*~enptv_high.*~enctv_high.*~enptv_mid.*~enctv_low;

                ptv_dose = ctv_high + ctv_mid + ctv_low + ptv_high + ptv_mid + ptv_low;
                
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = .4;
                d.Message = 'Modeling Radial dose distribution...';
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                pause(0.5)
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                %% Model Radial dose fall off
                % Combine expanded volumes
                enl_ptv = enptv_high | enptv_mid | enptv_low;
                ptv_dist = bwdist(enl_ptv);
                ptv_dist = ptv_dist*x_res;

                doseRadial = zeros(size(ptv_dist));
                outSideSegment = ~enl_ptv;

                doseRadial(outSideSegment) =  73.165*exp(-0.0572*(ptv_dist(outSideSegment))) ...
                    + 28.6811 * exp(-0.0117*(ptv_dist(outSideSegment))); % exponential with 2 term
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = .6;
                d.Message = 'Modeling INF-SUP dose distribution...';
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                pause(0.5)
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

                %% Exclude INF-SUP part of dose fall off - which is modeled separtely
                % Find non-zero PTV slices
                for sl = 1:size(enl_ptv, 3)
                    % Check if the current slice has any non-zero elements
                    if any(enl_ptv(:, :, sl), 'all')
                        Slices(sl,1) = sl; % Store the index
                    end
                end
                Slices = Slices(~all(Slices==0,2),:);% Find non-zero PTV slices
                inf_sup_ptv = zeros(size(enl_ptv));
                inf_sup_ptv(:,:,Slices(1)-2:Slices(end)+2) = 1;
                doseRadial = doseRadial.*inf_sup_ptv;

                % Model INF-SUP dose fall off
                exp_slices = round(30/z_res);

                %INF part               
                % Compute the region properties for the blobs (centroid and area)
                props = regionprops(enl_ptv(:,:,Slices(1)), 'Centroid', 'Area');
                % Get the centroids and areas
                centroids = cat(1, props.Centroid);  % Concatenate all centroids
                areas = [props.Area];  % Extract areas of each blob

                % Calculate the weighted average centroid based on area
                com_ptv = sum(centroids .* areas', 1) / sum(areas);
                com_ptv = round(com_ptv);
                exp_ptv = zeros(size(enl_ptv,1),size(enl_ptv,2),exp_slices);
                exp_ptv(com_ptv(2),com_ptv(1),1) = 1;

                ptvIS_dist = bwdist(exp_ptv)*z_res;
                outSideSegment = ~exp_ptv;
                doseINFSUP1 = zeros(size(exp_ptv));
                doseINFSUP1(outSideSegment) =  -0.0332*ptvIS_dist(outSideSegment).^3 ...
                    + 1.4137*ptvIS_dist(outSideSegment).^2 - 20.293*ptvIS_dist(outSideSegment) +103.73; % polynomial with 3 term

                % SUP part
                com_ptv = regionprops(enl_ptv(:,:,Slices(end)),'Centroid');
                com_ptv = com_ptv.Centroid;
                com_ptv = round(com_ptv);
                exp_ptv = zeros(size(enl_ptv,1),size(enl_ptv,2),exp_slices);
                exp_ptv(com_ptv(2),com_ptv(1),1) = 1;

                ptvIS_dist = bwdist(exp_ptv)*z_res;
                outSideSegment = ~exp_ptv;
                doseINFSUP2 = zeros(size(exp_ptv));
                doseINFSUP2(outSideSegment) =  -0.0332*ptvIS_dist(outSideSegment).^3 ...
                    + 1.4137*ptvIS_dist(outSideSegment).^2 - 20.293*ptvIS_dist(outSideSegment) +103.73; % polynomial with 3 term


                doseINFSUP = zeros(size(enl_ptv));
                count=1;
                for sl=Slices(1)-1:-1:(Slices(1)-exp_slices)
                    doseINFSUP(:,:,sl) = doseINFSUP1(:,:,count);
                    count=count+1;
                end
                doseINFSUP(:,:,Slices(end):Slices(end)+exp_slices-1) = doseINFSUP2;
                inf_sup_ptv = zeros(size(enl_ptv));
                start_sl = Slices(1)-round(30/z_res); %3cm INF
                end_sl = Slices(end)+round(30/z_res); %3cm SUP
                inf_sup_ptv(:,:,start_sl:Slices(1)-3) = 1;
                inf_sup_ptv(:,:,Slices(end)+3:end_sl) = 1;

                doseINFSUP = doseINFSUP.*inf_sup_ptv;
                % combine dose radial and dose INF-SUP
                doseMatrix = doseRadial + doseINFSUP;
                doseMatrix(doseMatrix<0)=0;
                % normalize to ptv_low/ptv_mid dose                            
                if low_dose_ptv~=0
                    doseMatrix =  doseMatrix*(low_dose_ptv/high_dose_ptv)*90/max(doseMatrix,[],'all');
                elseif mid_dose_ptv~=0
                    doseMatrix =  doseMatrix*(mid_dose_ptv/high_dose_ptv)*90/max(doseMatrix,[],'all');
                end
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = .8;
                d.Message = 'Generating dose distribution...';
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                pause(0.5)
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

                %% Model OAR dose fall off
                OARs = app.GenDose.OARs;
                app.GenDose.Margin = [20,20,20,20,15,15];%[A,P,R,L,I,S]
                OAR_inM = GrowMargin(app,'OARs'); 
                OAR_inM = OAR_inM.*~enl_ptv.*~OARs;
                app.GenDose.Margin = [40,40,40,40,30,30];%[A,P,R,L,I,S]
                OAR_enl = GrowMargin(app,'OARs'); 
                OAR_enl = OAR_enl.*~enl_ptv.*~OARs;
                % Define the structuring element
                se = strel('sphere', 3); % A spherical structuring element with radius 2
                % Step 1: Fill small gaps using morphological closing
                OAR_inM = imclose(OAR_inM, se); OAR_enl = imclose(OAR_enl, se);
                % Step 2: Optionally smooth the segment with erosion and dilation
                OAR_inM = imopen(OAR_inM, se); OAR_enl = imopen(OAR_enl, se);
                % Step 3: Ensure binary output (optional, depends on your application)
                OAR_inM = double(imbinarize(OAR_inM)); OAR_enl = double(imbinarize(OAR_enl));
                OAR_inM = (OAR_inM.*~(enl_ptv+OARs)).*body;
                OAR_enl = (OAR_enl.*~(OAR_inM+enl_ptv+OARs)).*body;

                oar_doses = 0.25*(OARs.*doseMatrix) + 0.4*(OAR_inM.*doseMatrix) + 0.5*(OAR_enl.*doseMatrix);

                % normalize to ptv_low dose
                oar_doses = oar_doses*(low_dose_ptv/high_dose_ptv)*50/max(oar_doses,[],'all');

                % combine all doses
                dose1 = ptv_dose + oar_doses;
                bk = double(dose1<=0);
                dose1 = (dose1 + (doseMatrix.*bk)).*body;

                % normalize to Highest dose
                high_pres_dose = max([high_dose_ptv,high_dose_ctv]);
                dose1 = (dose1*high_pres_dose/100);
                % append dose to app
                app.Dose = dose1;
                app.Label.Text = 'Doses Generated successfully!!!';

                % Reset current button
                app.GenerateDoseButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default
                app.GenerateDoseButton.FontWeight = 'normal';

                % Enable and highlight the next button
                app.WriteRTplanButton.Enable = 'on';
                app.WriteRTplanButton.BackgroundColor = [0.47, 0.67, 0.19]; % Green
                app.WriteRTplanButton.FontWeight = 'bold';

                % Enable and highlight the next button               
                app.ViewDoseDistributionButton.Enable = 'on';
                
            catch ME
                app.Label.Text = "Error Dose Generation!";
                disp(ME.message);
            end

            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d.Value = 1;
            d.Message = 'Finished - Generating dose distribution...';
            pause(0.5)
            if isvalid(d)
                close(d)
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%             

        end

        % Button pushed function: WriteRTdoseButton
        function WriteRTdoseButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d = uiprogressdlg(app.DoseGeneratorUIFigure,'Title','Please Wait',...
                'Message','Writing RT-DOSE file...','Cancelable', 'on'); 
            pause(0.5)
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            try
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = .1;
                d.Message = 'Writing RT-Dose file...';
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                pause(0.5)
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%                            

                % Now read the file
                planInfo =  dicominfo(fullfile(app.WorkFolder{1}, [app.PlanInfo.RTPlanLabel,'_SynRTPLAN.dcm']));

                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = .25;
                d.Message = 'Writing RT-DOSE file...';
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                pause(0.5)
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

                % Resample Simulated DOSE to original Dose file size
                % Calculate the new size based on the resolutions
                new_res = [app.DoseInfo.PixelSpacing',...
                    app.DoseInfo.GridFrameOffsetVector(2)];
                original_res = app.GenDose.Res;
                original_res= [original_res(1), original_res(2), original_res(3)];

                % Resample dose
                resamp_dose = resample_data(app,original_res,new_res);

                % Crop the dose matrix to original dose size
                ImPos = app.ctInfo.ImagePositionPatient{1, 1};
                DosPos = app.DoseInfo.ImagePositionPatient;
                DosRow = app.DoseInfo.Rows;
                DosCol = app.DoseInfo.Columns;
                StrtR = round((abs(ImPos(2))-abs(DosPos(2)))/new_res(1))+1;
                EndR = StrtR + DosRow-1;
                StrtC = round((abs(ImPos(1))-abs(DosPos(1)))/new_res(2))+1;
                EndC = StrtC + DosCol-1;
             
                if size(resamp_dose, 1) < DosRow
                    % Adjust the number of rows to match DosRow
                    newRows = DosRow - size(resamp_dose, 1);
                    resamp_dose = cat(1, resamp_dose, zeros(newRows, size(resamp_dose, 2), size(resamp_dose, 3)));
                end

                if size(resamp_dose, 2) < DosCol
                    % Adjust the number of columns to match DosCol
                    newCols = DosCol - size(resamp_dose, 2);
                    resamp_dose = cat(2, resamp_dose, zeros(size(resamp_dose, 1), newCols, size(resamp_dose, 3)));
                end
                % Adjust the EndR and EndC - if more due to rounding-off
                if EndR>size(resamp_dose,1)
                    EndR = size(resamp_dose,1);
                end

                if EndC>size(resamp_dose,2)
                    EndC = size(resamp_dose,2);
                end
                 % Adjust the StrtR and StrtC - if zero
                 if StrtR<=0; StrtR=1; end
                 if StrtC<=0; StrtC=1; end

                resamp_dose = resamp_dose(StrtR:EndR, StrtC:EndC,:);

                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = 0.6;
                d.Message = 'Writing RT-DOSE file...';
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                pause(0.5)
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

                % Given file path
                file_path = app.DoseInfo.Filename;

                % Find the position of the last backslash
                last_slash_idx = find(file_path == '\', 1, 'last');

                % Extract the part of the string after the last backslash
                file_name = file_path(last_slash_idx + 1:end);
                dinfo = app.DoseInfo;

                dinfo.Width = size(resamp_dose,2);
                dinfo.Height = size(resamp_dose,1);
                dinfo.Rows = size(resamp_dose,1);
                dinfo.Columns = size(resamp_dose,2);
                dinfo.NumberOfFrames = size(resamp_dose,3);
                dinfo.ImagePositionPatient = app.DoseInfo.ImagePositionPatient;

                dinfo.SOPInstanceUID = dicomuid;
                dinfo.SeriesInstanceUID = dicomuid;
                dinfo.StudyInstanceUID = planInfo.StudyInstanceUID; % Link to the plan
                dinfo.ReferencedRTPlanSequence.Item_1.ReferencedSOPInstanceUID = planInfo.SOPInstanceUID;
                dinfo.ReferencedRTPlanSequence.Item_1.ReferencedSOPClassUID = planInfo.SOPClassUID;

                resamp_dose = resamp_dose./dinfo.DoseGridScaling;
                % File name
                fname = strcat('sDOSE_RP_',file_name);
                % Write dicom data
                dicomwrite(uint32(reshape(resamp_dose, [size(resamp_dose,1) size(resamp_dose,2) 1 size(resamp_dose,3)])),...
                    fullfile(app.WorkFolder{1,1},fname), dinfo, 'CompressionMode', 'None', 'CreateMode', ...
                    'Copy', 'Endian', 'ieee-le');
                app.Label.Text = 'RTDose Write successfull!!!';

                % Reset current button
                app.WriteRTdoseButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default
                app.WriteRTdoseButton.FontWeight = 'normal';
            catch ME
                app.Label.Text = "Error RT Dose Write!";
                disp(ME.message);
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d.Value = 1;
            d.Message = 'Finished - Writing RT-DOSE file...';
            pause(0.5)
            if isvalid(d)
                close(d)
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

        end

        % Callback function
        function DoseButtonGroupSelectionChanged(app, event)
            selectedButton = app.DoseButtonGroup.SelectedObject.Text;
            if strcmpi(selectedButton, 'ON')
                imshow3D(app.ctData,'parent',app.UIAxes);
                app.overlayImages(app.Dose);
            end
        end

        % Button pushed function: ViewDoseDistributionButton
        function ViewDoseDistributionButtonPushed(app, event)
            % Create a figure window
            figure;
            ctImage = app.ctData;
            doseImage = app.Dose;
            % Set up slice range for scrolling
            sliceRange = 1:size(ctImage, 3); % Slice range for scrolling

            % Define alpha values for each image
            alphaCT = 1;  % Alpha value for CT image (opaque)
            alphaDose = 0.7; % Alpha value for dose image (semi-transparent)

            % Create an axis to display slices
            ax = axes;

            % Initialize the slice index
            sliceIndex = 1;

            % Display the CT image (grayscale) in the first slice
            hCT = imshow(ctImage(:,:,sliceIndex), [], 'Parent', ax);
            colormap(ax, gray); % Set the CT image to grayscale

            % Hold on to overlay other images
            hold on;

            % Create the custom parula-based colormap with a black base
            customColormap = [zeros(1, 3); parula(256)];  % Add black color at the beginning

            % Display the dose image (using parula colormap)
            hDose = imagesc(doseImage(:,:,sliceIndex), 'Parent', ax);
            set(hDose, 'AlphaData', alphaDose * ones(size(doseImage(:,:,sliceIndex)))); % Set alpha for dose
            colormap(ax, customColormap); % Use parula for dose image

            % Set initial values for the dose range
            minDoseValue = round(max(doseImage, [], 'all') / 2);  % Set min dose to half of the maximum dose
            maxDoseValue = max(doseImage, [], 'all');  % Set max dose to the maximum value in the doseImage

            % Create UI controls for the dose range
            uicontrol('Style', 'text', 'String', 'Min Dose (Gy):', 'Position', [30 20 100 20]);
            minDoseBox = uicontrol('Style', 'edit', 'String', num2str(minDoseValue), 'Position', [120 20 60 20], ...
                'Callback', @(src, event) updateDoseRange());

            uicontrol('Style', 'text', 'String', 'Max Dose (Gy):', 'Position', [180 20 100 20]);
            maxDoseBox = uicontrol('Style', 'edit', 'String', num2str(maxDoseValue), 'Position', [270 20 60 20], ...
                'Callback', @(src, event) updateDoseRange());

            % Add label for slice index
            uicontrol('Style', 'text', 'String', 'Slice:', ...
                'Position', [330 20 60 20], 'HorizontalAlignment', 'right');

            % Add text box for slice index
            sliceIndexBox = uicontrol('Style', 'edit', 'String', num2str(sliceIndex), ...
                'Position', [390 20 60 20], ...
                'Callback', @(src, event) updateSliceIndex(src, ctImage, doseImage, ax, hCT, hDose, sliceRange, customColormap));

            % Add buttons for slice navigation
            uicontrol('Style', 'pushbutton', 'String', 'Previous', ...
                'Position', [470 20 60 20], 'Callback', @(src, event) navigateSlice(-1, ctImage, doseImage, ax, hCT, hDose, sliceIndexBox, sliceRange, customColormap));
            uicontrol('Style', 'pushbutton', 'String', 'Next', ...
                'Position', [540 20 60 20], 'Callback', @(src, event) navigateSlice(1, ctImage, doseImage, ax, hCT, hDose, sliceIndexBox, sliceRange, customColormap));

            % Store the handles in the figure's UserData property
            set(gcf, 'UserData', struct('minDoseBox', minDoseBox, 'maxDoseBox', maxDoseBox, 'sliceIndexBox', sliceIndexBox));

            % Enable mouse scroll for slice navigation
            set(gcf, 'WindowScrollWheelFcn', @(src, event) scrollSlices(event, ctImage, doseImage, ax, hCT, hDose, sliceIndexBox, sliceRange, customColormap));

            % Function to scroll through slices
            function scrollSlices(event, ctImage, doseImage, ax, hCT, hDose, sliceIndexBox, sliceRange, customColormap)
                currentSliceIndex = str2double(sliceIndexBox.String);
                newSliceIndex = currentSliceIndex + sign(event.VerticalScrollCount);
                newSliceIndex = max(sliceRange(1), min(sliceRange(end), newSliceIndex));
                setSlice(newSliceIndex, ctImage, doseImage, ax, hCT, hDose, sliceIndexBox, customColormap);
            end

            % Function to update slice index from text box
            function updateSliceIndex(src, ctImage, doseImage, ax, hCT, hDose, sliceRange, customColormap)
                newSliceIndex = str2double(src.String);
                if isnan(newSliceIndex) || newSliceIndex < sliceRange(1) || newSliceIndex > sliceRange(end)
                    errordlg('Invalid slice index. Please enter a value within the valid range.');
                    src.String = num2str(sliceRange(1)); % Reset to first slice on error
                    return;
                end
                setSlice(newSliceIndex, ctImage, doseImage, ax, hCT, hDose, src, customColormap);
            end

            % Function to navigate slices using buttons
            function navigateSlice(step, ctImage, doseImage, ax, hCT, hDose, sliceIndexBox, sliceRange, customColormap)
                currentSliceIndex = str2double(sliceIndexBox.String);
                newSliceIndex = max(sliceRange(1), min(sliceRange(end), currentSliceIndex + step));
                setSlice(newSliceIndex, ctImage, doseImage, ax, hCT, hDose, sliceIndexBox, customColormap);
            end

            % Function to update dose range based on user input
            function [minDose, maxDose] = updateDoseRange()
                userData = get(gcf, 'UserData');
                minDoseBox = userData.minDoseBox;
                maxDoseBox = userData.maxDoseBox;
                minDose = str2double(minDoseBox.String);
                maxDose = str2double(maxDoseBox.String);
                if isnan(minDose) || isnan(maxDose) || minDose >= maxDose
                    errordlg('Please enter valid minimum and maximum dose values (min < max).');
                    return;
                end
            end

            % Helper function to set and display the slice
            function setSlice(sliceIndex, ctImage, doseImage, ax, hCT, hDose, sliceIndexBox, customColormap)
                set(hCT, 'CData', ctImage(:,:,sliceIndex));
                colormap(ax, 'gray');
                [minDose, maxDose] = updateDoseRange();
                set(hDose, 'CData', doseImage(:,:,sliceIndex));
                clim(ax, [minDose, maxDose]);
                colormap(ax, customColormap);
                set(sliceIndexBox, 'String', num2str(sliceIndex));
                title(ax, ['Slice: ' num2str(sliceIndex)]);
            end


        end

        % Callback function: not associated with a component
        function HyperlinkClicked(app, event)
            % Specify the URL to open
            url = 'https://horizonnb.ca/facilities/saint-john-regional-hospital/';
            % Open the URL in the default web browser
            web(url, '-browser');
        end

        % Button pushed function: WriteRTplanButton
        function WriteRTplanButtonPushed(app, event)
                   %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d = uiprogressdlg(app.DoseGeneratorUIFigure,'Title','Please Wait',...
                'Message','Writing RT-Plan file...','Cancelable', 'on'); 
            % Check if the user canceled
            if d.CancelRequested
                app.Label.Text = 'Operation cancelled by user.';
                return;
            end
            pause(0.5)
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            try
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = 0.2;
                d.Message = 'Writing RT-Plan file...';
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                pause(0.5)
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                planInfo = app.PlanInfo;

                % Generate new UIDs
                newSOPInstanceUID = dicomuid;
                newSeriesUID = dicomuid;
                newStudyUID = dicomuid;

                % Update identifiers
                planInfo.SOPInstanceUID = newSOPInstanceUID;
                planInfo.SeriesInstanceUID = newSeriesUID;
                planInfo.StudyInstanceUID = newStudyUID;
                planInfo.ApprovalStatus = 'UNAPPROVED';

                % Set related file meta info explicitly
                planInfo.MediaStorageSOPClassUID = planInfo.SOPClassUID;  % should already be RT Plan UID
                planInfo.MediaStorageSOPInstanceUID = newSOPInstanceUID;

                % Update plan name
                oldPlanName = planInfo.RTPlanLabel;
                planInfo.RTPlanLabel = [oldPlanName '_SynRTPLAN'];
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
                d.Value = 0.6;
                d.Message = 'Writing RT-Plan file...';
                % Check if the user canceled
                if d.CancelRequested
                    app.Label.Text = 'Operation cancelled by user.';
                    return;
                end
                pause(0.5)
                %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%

                % Save updated RT Plan
                dicomwrite([], fullfile(app.WorkFolder{1,1}, [planInfo.RTPlanLabel,'.dcm']), ...
                    planInfo, ...
                    'CreateMode','Copy', ...
                    'CompressionMode', 'None', ...
                    'Endian', 'ieee-le');

                app.Label.Text = 'RTPlan Write successfull!!!';

                % Reset current button
                app.WriteRTplanButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default
                app.WriteRTplanButton.FontWeight = 'normal';

                % Enable and highlight the next button
                app.WriteRTdoseButton.Enable = 'on';
                app.WriteRTdoseButton.BackgroundColor = [0.47, 0.67, 0.19]; % Green
                app.WriteRTdoseButton.FontWeight = 'bold';
           catch ME
                app.Label.Text = "Error RT plan Writing!";
                disp(ME.message);
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
            d.Value = 1;
            d.Message = 'Finished - Writing RT-Plan file...';
            pause(0.5)
            if isvalid(d)
                close(d)
            end
            %%%%%%%%%%%%%%%%%% Progess bar %%%%%%%%%%%%%%%%
        end

        % Menu selected function: HelpcontentsMenu
        function HelpcontentsMenuSelected(app, event)
           web(fullfile(pwd, 'UserGuide.pdf'), '-browser');
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)

            % === Layout Settings ===
            figWidth = 440;
            figHeight = 280;
            lineSpacing = 26;
            startY = figHeight - 60;  % Starting vertical position for text
            xOffset = 110;
            logoSize = [80, 80];

            % Screen center positioning
            screenSize = get(0, 'ScreenSize');
            figLeft = (screenSize(3) - figWidth) / 1;
            figBottom = (screenSize(4) - figHeight) / 2;

            % Create the figure
            d = uifigure('Name', 'About', ...
                'Position', [figLeft, figBottom, figWidth, figHeight], ...
                'WindowStyle', 'modal', ...
                'Color', [0.96 0.98 1.00]);

            drawnow;

            % === Logo ===
            try
                uiimage(d, ...
                    'ImageSource', fullfile(pwd, 'pic_app.JPG'), ...
                    'Position', [20, figHeight - 100, logoSize(1), logoSize(2)]);
            catch
                % Skip image if not found
            end

            % === App Title ===
            uilabel(d, ...
                'Text', 'Radiotherapy Dose Planner', ...
                'FontSize', 16, ...
                'FontWeight', 'bold', ...
                'Position', [xOffset, startY, 300, 30]);

            % === Version Info ===
            uilabel(d, ...
                'Text', 'Version 1.0', ...
                'FontSize', 12, ...
                'Position', [xOffset, startY - 1 * lineSpacing, 300, 22]);

            % === Developer Info ===
            uilabel(d, ...
                'Text', 'Developed by', ...
                'FontSize', 12, ...
                'Position', [xOffset, startY - 2 * lineSpacing, 300, 22]);

            uilabel(d, ...
                'Text', 'SA Yoganathan PhD', ...
                'FontSize', 12, ...
                'Position', [xOffset, startY - 3 * lineSpacing, 300, 22]);

            uilabel(d, ...
                'Text', 'Medical Physicist, Radiation Oncology', ...
                'FontSize', 12, ...
                'Position', [xOffset, startY - 4 * lineSpacing, 300, 22]);

            uilabel(d, ...
                'Text', 'Saint John Regional Hospital, New Brunswick', ...
                'FontSize', 12, ...
                'Position', [xOffset, startY - 5 * lineSpacing, 320, 22]);

            % === Copyright ===
            uilabel(d, ...
                'Text', '© 2025 All rights reserved.', ...
                'FontSize', 12, ...
                'Position', [xOffset, startY - 6 * lineSpacing, 300, 22]);

            % === Website Button ===
            uibutton(d, 'push', ...
                'Text', 'Visit Website', ...
                'FontSize', 12, ...
                'Position', [160, 20, 120, 30], ...
                'ButtonPushedFcn', @(btn, event) ...
                web('https://horizonnb.ca/facilities/saint-john-regional-hospital/', '-browser'));        

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DoseGeneratorUIFigure = uifigure('Visible', 'off');
            app.DoseGeneratorUIFigure.Name = 'Dose Generator';
            app.DoseGeneratorUIFigure.Icon = fullfile(pathToMLAPP, 'pic_app.JPG');
            app.HelpMenu = uimenu(app.DoseGeneratorUIFigure);
            app.HelpMenu.Text = 'Help';
            app.HelpcontentsMenu = uimenu(app.HelpMenu);
            app.HelpcontentsMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpcontentsMenuSelected, true);
            app.HelpcontentsMenu.Text = 'Help contents';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About...';
            app.Panel = uipanel(app.DoseGeneratorUIFigure);
            app.LoadDicomButton = uibutton(app.Panel, 'push');
            app.LoadDicomButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDicomButtonPushed, true);
            app.LoadDicomButton.Text = 'Load Dicom';
            app.GenerateDoseButton = uibutton(app.Panel, 'push');
            app.GenerateDoseButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDoseButtonPushed, true);
            app.GenerateDoseButton.Enable = 'off';
            app.GenerateDoseButton.Text = 'Generate Dose';
            app.WriteRTdoseButton = uibutton(app.Panel, 'push');
            app.WriteRTdoseButton.ButtonPushedFcn = createCallbackFcn(app, @WriteRTdoseButtonPushed, true);
            app.WriteRTdoseButton.Enable = 'off';
            app.WriteRTdoseButton.Text = 'Write RT dose';
            app.ReadContoursButton = uibutton(app.Panel, 'push');
            app.ReadContoursButton.ButtonPushedFcn = createCallbackFcn(app, @ReadContoursButtonPushed2, true);
            app.ReadContoursButton.Enable = 'off';
            app.ReadContoursButton.Text = 'Read Contours';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cropped-Horizon_Logo_CMYK.png');
            app.ViewDoseDistributionButton = uibutton(app.Panel, 'push');
            app.ViewDoseDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDoseDistributionButtonPushed, true);
            app.ViewDoseDistributionButton.Enable = 'off';
            app.ViewDoseDistributionButton.Text = 'View Dose Distribution';
            app.StatusLabel = uilabel(app.Panel);
            app.StatusLabel.Text = 'Status:';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'pic_app.JPG');
            app.WriteRTplanButton = uibutton(app.Panel, 'push');
            app.WriteRTplanButton.ButtonPushedFcn = createCallbackFcn(app, @WriteRTplanButtonPushed, true);
            app.WriteRTplanButton.Enable = 'off';
            app.WriteRTplanButton.Text = 'Write RT plan';
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Hospital-Exterior_Morning-6.jpg');
            app.PTVsListBoxLabel = uilabel(app.DoseGeneratorUIFigure);
            app.PTVsListBoxLabel.Text = 'PTVs';
            app.PTVsListBox = uilistbox(app.DoseGeneratorUIFigure);
            app.PTVsListBox.Items = {};
            app.PTVsListBox.Value = {};
            app.CTVsListBoxLabel = uilabel(app.DoseGeneratorUIFigure);
            app.CTVsListBoxLabel.Text = 'CTVs';
            app.CTVsListBox = uilistbox(app.DoseGeneratorUIFigure);
            app.CTVsListBox.Items = {};
            app.CTVsListBox.Value = {};
            app.OARsListBoxLabel = uilabel(app.DoseGeneratorUIFigure);
            app.OARsListBoxLabel.Text = 'OARs';
            app.OARsListBox = uilistbox(app.DoseGeneratorUIFigure);
            app.OARsListBox.Items = {};
            app.OARsListBox.Value = {};
            app.Label = uilabel(app.DoseGeneratorUIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.DoseGeneratorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RP_All_Site

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoseGeneratorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoseGeneratorUIFigure)
        end
    end
end